classdef Copy_of_draw_app < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                    matlab.ui.Figure
        GridLayout                  matlab.ui.container.GridLayout
        LeftPanel                   matlab.ui.container.Panel
        UploadimageButton           matlab.ui.control.Button
        CannyfilterparameterSlider  matlab.ui.control.Slider
        CannyfilterparameterLabel   matlab.ui.control.Label
        RightPanel                  matlab.ui.container.Panel
        LaunchdrawingButton         matlab.ui.control.Button
        UIAxes2                     matlab.ui.control.UIAxes
        UIAxes                      matlab.ui.control.UIAxes
    end

    % Properties that correspond to apps with auto-reflow
    properties (Access = private)
        onePanelWidth = 576;
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Value changed function: CannyfilterparameterSlider
        function CannyfilterparameterSliderValueChanged(app, event)
            global canny_param;
            global a;
            global n_cols;
            global n_rows;

            global edge_inv;
            canny_param = app.CannyfilterparameterSlider.Value;

            penGirth = 0.15; % Pen girth in cm
            n_cols = int16(7.85/penGirth); % number of colums drawn, 7.85 = pi*2.5
            n_rows = int16(10/penGirth); % number of lines
            fprintf("n_cols = %i | n_rows = %i \n", n_cols, n_rows);

            im_grey = rgb2gray(a); % grey image
            im_scaled = imresize(im_grey,[n_rows, n_cols]); % resized to fit the cylinder
            im_sharped = imsharpen(im_scaled); % sharp the edges for better edge detection
            %figure, imshow(im_grey);
            edge_im = edge(im_sharped,'canny', canny_param);  % keep the edges, paramater to vary for each image for good results
            edge_inv = ~edge_im; % invert black and white
            imshow(edge_inv, 'Parent', app.UIAxes2);

            % Formating for Arduino syntax
            n_pixels = sum(edge_inv(:) == 0); % number of pixels to draw
            k = 1;
            id_pixels = zeros([n_pixels, 2]);
            for i = 1:n_cols
                for j = 1:n_rows
                    if (edge_inv(j, i) == 0)
                        id_pixels(k, 1) = i;
                        id_pixels(k, 2) = j;
                        k = k+1;
                    end
                end
            end
            writematrix(id_pixels, "id_pixels.txt");
            %writematrix(edge_inv,'im_to_draw.txt'); % write the dot matrix into a file
        end

        % Button pushed function: UploadimageButton
        function UploadimageButtonPushed(app, event)
            global a;
            [filename, pathname] = uigetfile('*.*', 'Pick an Image');
            filename = strcat(pathname, filename);
            a = imread(filename);
            imshow(a, 'Parent', app.UIAxes)
        end

        % Button pushed function: LaunchdrawingButton
        function LaunchdrawingButtonPushed(app, event)
            %global n_cols;
            %global n_rows;
            %global edge_inv;
            %arduino_board = arduino('COM16', 'Uno', 'Libraries', 'Servo');
            %servo_cylinder = servo(arduino_board, 'D10');
            %servo_pen = servo(arduino_board, 'D9');

            %delete(instrfindall);
            %comm_arduino=serialport("COM16", 115200);
            %comm_arduino.flush();
            %clear comm_arduino;
            %comm_arduino=serialport('COM16', 115200);

            %binary_image = uint8(edge_inv);
            %image_vector = transpose(reshape(binary_image, 1, []));
            %binary_string = sprintf('%1.0f',double(image_vector));
            %fprintf("Type of binary string = %s \n", class(binary_string));

            %comma_indices = zeros([1, n_cols-1]);
            %transmitted_image = binary_string;
            %for j = 1:n_cols-1
            %    comma_indices(1, j) = j*n_rows;
            %    transmitted_image = insertAfter(transmitted_image, comma_indices(1,j), ";");
            %end
            %(1:n_rows:length(image_vector)) + (0:n_rows-1);
            %transmitted_image = insertAfter(binary_string, comma_indices, ";");
            %transmitted_image = insertAfter(transmitted_image, 0, "<");
            %transmitted_image = insertAfter(transmitted_image, strlength(transmitted_image), ">");
            %write(comm_arduino, transmitted_image, "string");
            %read(comm_arduino, 10, "string");
            %fprintf(transmitted_image);
        end

        % Changes arrangement of the app based on UIFigure width
        function updateAppLayout(app, event)
            currentFigureWidth = app.UIFigure.Position(3);
            if(currentFigureWidth <= app.onePanelWidth)
                % Change to a 2x1 grid
                app.GridLayout.RowHeight = {480, 480};
                app.GridLayout.ColumnWidth = {'1x'};
                app.RightPanel.Layout.Row = 2;
                app.RightPanel.Layout.Column = 1;
            else
                % Change to a 1x2 grid
                app.GridLayout.RowHeight = {'1x'};
                app.GridLayout.ColumnWidth = {220, '1x'};
                app.RightPanel.Layout.Row = 1;
                app.RightPanel.Layout.Column = 2;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.SizeChangedFcn = createCallbackFcn(app, @updateAppLayout, true);
            app.GridLayout = uigridlayout(app.UIFigure);
            app.LeftPanel = uipanel(app.GridLayout);
            app.CannyfilterparameterLabel = uilabel(app.LeftPanel);
            app.CannyfilterparameterLabel.Text = {'Canny filter '; 'parameter'};
            app.CannyfilterparameterSlider = uislider(app.LeftPanel);
            app.CannyfilterparameterSlider.Limits = [0 0.5];
            app.CannyfilterparameterSlider.ValueChangedFcn = createCallbackFcn(app, @CannyfilterparameterSliderValueChanged, true);
            app.UploadimageButton = uibutton(app.LeftPanel, 'push');
            app.UploadimageButton.ButtonPushedFcn = createCallbackFcn(app, @UploadimageButtonPushed, true);
            app.UploadimageButton.Text = 'Upload image';
            app.RightPanel = uipanel(app.GridLayout);
            app.UIAxes = uiaxes(app.RightPanel);
            title(app.UIAxes, 'Original Image')
            zlabel(app.UIAxes, 'Z')
            app.UIAxes2 = uiaxes(app.RightPanel);
            title(app.UIAxes2, 'Image to Draw')
            zlabel(app.UIAxes2, 'Z')
            app.LaunchdrawingButton = uibutton(app.RightPanel, 'push');
            app.LaunchdrawingButton.ButtonPushedFcn = createCallbackFcn(app, @LaunchdrawingButtonPushed, true);
            app.LaunchdrawingButton.Text = 'Launch drawing';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Copy_of_draw_app

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end